classdef piano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        SampleRatesPanel              matlab.ui.container.Panel
        GridLayout_3                  matlab.ui.container.GridLayout
        Base_Label                    matlab.ui.control.Label
        FrameSIze_EditField           matlab.ui.control.NumericEditField
        FrameSIzeLabel                matlab.ui.control.Label
        Base_EditField                matlab.ui.control.NumericEditField
        MotionCapture_EditField       matlab.ui.control.NumericEditField
        BloodOxygention_EditField     matlab.ui.control.NumericEditField
        MotionCaptureLabel            matlab.ui.control.Label
        BloodOxygention_Label         matlab.ui.control.Label
        sEMG_Label                    matlab.ui.control.Label
        sEMG_EditField                matlab.ui.control.NumericEditField
        MotionCaptureMarkerTolerancePanel  matlab.ui.container.Panel
        GridLayout_2                  matlab.ui.container.GridLayout
        MotionCaptureTol_Z_EditField  matlab.ui.control.NumericEditField
        MotionCaptureTol_Y_EditField  matlab.ui.control.NumericEditField
        MotionCaptureTol_X_EditField  matlab.ui.control.NumericEditField
        ZLabel_2                      matlab.ui.control.Label
        YLabel_2                      matlab.ui.control.Label
        XLabel_2                      matlab.ui.control.Label
        MotionCaptureMarkerCoordinatesPanel  matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        MotionCapture_Z_EditField     matlab.ui.control.NumericEditField
        MotionCapture_Y_EditField     matlab.ui.control.NumericEditField
        ZLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        MotionCapture_X_EditField     matlab.ui.control.NumericEditField
        SynchronizeButton             matlab.ui.control.Button
        Label_MotionCapture           matlab.ui.control.Label
        Label_sEMG                    matlab.ui.control.Label
        Label_MIDI                    matlab.ui.control.Label
        Label_BloodOxygention         matlab.ui.control.Label
        MotionCaptureButton           matlab.ui.control.Button
        sEMGsensorButton              matlab.ui.control.Button
        MIDIcaptureButton             matlab.ui.control.Button
        BloodOxygentionSensorButton   matlab.ui.control.Button
        renderTab                     matlab.ui.container.Tab
        SETTINGSButtonGroup           matlab.ui.container.ButtonGroup
        plot_options_afterSync_MotionCapture_color  matlab.ui.control.Button
        plot_options_afterSync_sEMG_color  matlab.ui.control.Button
        plot_options_afterSync_MIDI_color  matlab.ui.control.Button
        plot_options_afterSync_BloodOxygention_color  matlab.ui.control.Button
        plot_options_beforeSync_MotionCapture_color  matlab.ui.control.Button
        plot_options_beforeSync_sEMG_color  matlab.ui.control.Button
        plot_options_beforeSync_MIDI_color  matlab.ui.control.Button
        plot_options_beforeSync_BloodOxygention_Color  matlab.ui.control.Button
        Plot_options_plot_grid_2      matlab.ui.control.CheckBox
        Plot_options_plot_grid        matlab.ui.control.CheckBox
        Plot_options_plot_maxY        matlab.ui.control.NumericEditField
        yLabel                        matlab.ui.control.Label
        Plot_options_plot_minY        matlab.ui.control.NumericEditField
        Plot_options_plot_minX        matlab.ui.control.NumericEditField
        xLabel                        matlab.ui.control.Label
        Plot_options_plot_maxX        matlab.ui.control.NumericEditField
        PLOTLabel                     matlab.ui.control.Label
        plot_options_afterSync_BloodOxygention  matlab.ui.control.CheckBox
        plot_options_afterSync_MotionCapture  matlab.ui.control.CheckBox
        plot_options_afterSync_sEMG   matlab.ui.control.CheckBox
        plot_options_afterSync_MIDI   matlab.ui.control.CheckBox
        AFTERSYNCLabel                matlab.ui.control.Label
        BEFORESYNCLabel               matlab.ui.control.Label
        plot_options_beforeSync_BloodOxygention  matlab.ui.control.CheckBox
        plot_options_beforeSync_MotionCapture  matlab.ui.control.CheckBox
        plot_options_beforeSync_sEMG  matlab.ui.control.CheckBox
        plot_options_beforeSync_MIDI  matlab.ui.control.CheckBox
        graph                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %FILES
        SynchronisationSignalFile 
        BloodOxygentionSensorFile
        sEMGsensorFile 
        MotionCaptureFile
    end    

    properties (Access = private)
        %MARKER DATA
        markerLocation; 
        markerTolerance; 
        
        %SAMPLE RATES
        sEMG_sample_rate;
        BloodOxygention_sample_rate;
        MotionCapture_sample_rate;
        base_sample_rate;
        frame_size;

    end

    properties(Access = public)
        %ploting
        nonSyncPlots 
        syncPlots 

        %colors
        beforeSync_MIDIColor           = [0.5725, 0.2078, 0.7686]
        beforeSync_sMEGColor           = [0.9882, 0.4745, 0.3451]
        beforeSync_BloodOxygentionColor= [0.6039, 0.8275, 0.2235]
        beforeSync_MotionCaptureColor  = [0.8745, 0.9294, 0.2784]

        afterSync_MIDIColor           = [0.5725, 0.2078, 0.7686]
        afterSync_sMEGColor           = [0.9882, 0.4745, 0.3451]
        afterSync_BloodOxygentionColor= [0.6039, 0.8275, 0.2235]
        afterSync_MotionCaptureColor  = [0.8745, 0.9294, 0.2784]
    end

    properties(Access = public)
        debugData1 = [0, 1, 2, 3, 4, 5, 6, 5, 4, 3, 2, 1, 0, 1, 2, 3, 4, 5, 4, 3, 4, 5, 6, 8, 10, 0];
        debugData2 = [100:-1:90];  
        debugData3 = [5, 10, 15, 20, 25, 30, 25, 20, 15, 10, 5, 0];  
        debugData4 = [3, 3, 3, 6, 6, 6, 9, 9, 9, 12, 12, 12, 9, 6, 3, 0]; 
        debugData5 = linspace(0, 1, 26); 
        debugData6 = randi([0, 20], 1, 26);
        

        Data_AfterSync_MIDI;
        Data_AfterSync_sEMG_L;
        Data_AfterSync_sEMG_R;
        Data_AfterSync_Blood_L;
        Data_AfterSync_Blood_R;
        Data_AfterSync_Motion;


    end
    
    methods (Access = private)
        
        function result = saveCord(app)
            coords = struct('X', app.markerLocation.X, 'Y', app.markerLocation.Y, 'Z', app.markerLocation.Z);
            jsonStr = jsonencode(coords);
            filename = 'coordinates.json';

            fid = fopen(filename, 'w');
            if fid == -1
                error('Cannot open file %s for writing.', filename);
            end

            fprintf(fid, '%s', jsonStr);
            fclose(fid);

            disp(['Data written to ' filename]);
        end

        function result = saveTolerance(app)
            coords = struct('X', app.markerTolerance.X, 'Y', app.markerTolerance.Y, 'Z', app.markerTolerance.Z);
            jsonStr = jsonencode(coords);
            filename = 'tolerance.json';

            fid = fopen(filename, 'w');
            if fid == -1
                error('Cannot open file %s for writing.', filename);
            end

            fprintf(fid, '%s', jsonStr);
            fclose(fid);

            disp(['Data written to ' filename]);
        end

        function result = loadCord(app)
            filename = 'coordinates.json';
            
            fid = fopen(filename, 'r');
            if fid == -1
                result = false;
                return;
            end
            
            rawData = fread(fid, inf, '*char')';
            fclose(fid);
            
            app.markerLocation = jsondecode(rawData);
           
            % Display the loaded data
            disp('Loaded coordinates:');
            disp(['X: ' num2str(app.markerLocation.X)]);
            disp(['Y: ' num2str(app.markerLocation.Y)]);
            disp(['Z: ' num2str(app.markerLocation.Z)]);
            
            result = true;
            return;
        end

        function result = loadTolerance(app)
            filename = 'tolerance.json';
            
            fid = fopen(filename, 'r');
            if fid == -1
                result = false;
                return;
            end
            
            rawData = fread(fid, inf, '*char')';
            fclose(fid);
            
            app.markerTolerance = jsondecode(rawData);
           
            % Display the loaded data
            disp('Loaded Tolerance:');
            disp(['X: ' num2str(app.markerLocation.X)]);
            disp(['Y: ' num2str(app.markerLocation.Y)]);
            disp(['Z: ' num2str(app.markerLocation.Z)]);
            
            result = true;
            return;
        end

        function result = reFreshplot(app)
            cla(app.graph);
            hold(app.graph, "on");
            
            legends = [];

            %Before sync
            if (app.nonSyncPlots(app.plot_options_beforeSync_BloodOxygention.Text))
                plot(app.graph, app.Data_AfterSync_Blood, "-.", "Color", app.beforeSync_BloodOxygentionColor);
                legends = [legends, "Before Sync: BloodOxygention"];
            end

            if (app.nonSyncPlots(app.plot_options_beforeSync_sEMG.Text))
                plot(app.graph, app.Data_AfterSync_sEMG, "-.", "Color",app.beforeSync_sMEGColor);
                legends = [legends, "Before Sync: sEMG"];
            end
            
            if (app.nonSyncPlots(app.plot_options_beforeSync_MotionCapture.Text))
                plot(app.graph, app.Data_AfterSync_Motion, "-.", "Color",app.beforeSync_MotionCaptureColor);
                legends = [legends, "Before Sync: MotionCapture"] ;
            end

            if (app.nonSyncPlots(app.plot_options_beforeSync_MIDI.Text))
                plot(app.graph, app.Data_AfterSync_MIDI, "-.", "Color", app.beforeSync_MIDIColor);
                legends = [legends, "Before Sync: MIDI"];
            end


            %after sync
            if (app.syncPlots(app.plot_options_afterSync_BloodOxygention.Text))
                plot(app.graph, app.Data_AfterSync_Blood_R, "Color", app.afterSync_BloodOxygentionColor);
                legends = [legends, "After Sync: BloodOxygention"];
            end

            if (app.syncPlots(app.plot_options_afterSync_sEMG.Text))
                plot(app.graph, app.Data_AfterSync_sEMG_R, "Color",app.afterSync_sMEGColor);
                legends = [legends, "After Sync: sEMG"];
            end

            if (app.syncPlots(app.plot_options_afterSync_MotionCapture.Text))
                plot(app.graph, app.Data_AfterSync_Motion, "Color",app.afterSync_MotionCaptureColor);
                legends = [legends, "After Sync: MotionCapture"];
            end

            if (app.syncPlots(app.plot_options_afterSync_MIDI.Text))
                plot(app.graph, app.Data_AfterSync_MIDI,  "Color", app.afterSync_MIDIColor);
                legends = [legends, "After Sync: MIDI"];
            end

            
            legend(app.graph, legends)
            hold(app.graph, "off");

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            if (app.loadCord() == false)
                app.markerLocation = struct('X', 0, 'Y', 0, 'Z', 0);
            end

            if (app.loadTolerance() == false)
                app.markerTolerance = struct('X', 0, 'Y', 0, 'Z', 0);
            end

            app.MotionCapture_X_EditField.Value = app.markerLocation.X;
            app.MotionCapture_Y_EditField.Value = app.markerLocation.Y;
            app.MotionCapture_Z_EditField.Value = app.markerLocation.Z;

            app.MotionCaptureTol_X_EditField.Value = app.markerTolerance.X;
            app.MotionCaptureTol_Y_EditField.Value = app.markerTolerance.Y;
            app.MotionCaptureTol_Z_EditField.Value = app.markerTolerance.Z;


            app.sEMG_sample_rate = app.sEMG_EditField.Value;
            app.BloodOxygention_sample_rate = app.BloodOxygention_EditField.Value;
            app.MotionCapture_sample_rate = app.MotionCapture_EditField.Value;
            app.base_sample_rate = app.Base_EditField.Value;
            app.frame_size = app.FrameSIze_EditField.Value;
            
            app.plot_options_beforeSync_BloodOxygention_Color.BackgroundColor = app.beforeSync_BloodOxygentionColor;
            app.plot_options_beforeSync_MIDI_color.BackgroundColor = app.beforeSync_MIDIColor;
            app.plot_options_beforeSync_sEMG_color.BackgroundColor = app.beforeSync_sMEGColor;
            app.plot_options_beforeSync_MotionCapture_color.BackgroundColor = app.beforeSync_MotionCaptureColor;

            app.plot_options_afterSync_BloodOxygention_color.BackgroundColor =  app.afterSync_BloodOxygentionColor;
            app.plot_options_afterSync_MIDI_color.BackgroundColor =             app.afterSync_MIDIColor;
            app.plot_options_afterSync_sEMG_color.BackgroundColor =             app.afterSync_sMEGColor;
            app.plot_options_afterSync_MotionCapture_color.BackgroundColor =    app.afterSync_MotionCaptureColor;

        end

        % Callback function
        function SynchronisationSignalpianoButtonPushed(app, event)
            [baseName, folder] = uigetfile();
            fullFileName = fullfile(folder, baseName);
            
            app.SynchronisationSignalFile = fullFileName;
            app.Label.Text = app.SynchronisationSignalFile;
        end

        % Button pushed function: BloodOxygentionSensorButton
        function BloodOxygentionSensorButtonPushed(app, event)
            
            [baseName, folder] = uigetfile();
            fullFileName = fullfile(folder, baseName);
            app.BloodOxygentionSensorFile = fullFileName;
            app.Label_BloodOxygention.Text = app.BloodOxygentionSensorFile;
        end

        % Callback function
        function DataAcquisitionUnitButtonPushed(app, event)
             app.DataAcquisitionUnitFile = uigetfile;
            app.Label3.Text = app.DataAcquisitionUnitFile;
        end

        % Button pushed function: MIDIcaptureButton
        function MIDIcaptureButtonPushed(app, event)
            [baseName, folder] = uigetfile();
            fullFileName = fullfile(folder, baseName);
            
            app.SynchronisationSignalFile = fullFileName;
            app.Label_MIDI.Text = app.SynchronisationSignalFile;
        end

        % Button pushed function: sEMGsensorButton
        function sEMGsensorButtonPushed(app, event)
            [baseName, folder] = uigetfile();
            fullFileName = fullfile(folder, baseName);
            app.sEMGsensorFile = fullFileName;
            app.Label_sEMG.Text = app.sEMGsensorFile;
        end

        % Button pushed function: MotionCaptureButton
        function MotionCaptureButtonPushed(app, event)
            [baseName, folder] = uigetfile();
            fullFileName = fullfile(folder, baseName);
            app.MotionCaptureFile = fullFileName;
            app.Label_MotionCapture.Text = app.MotionCaptureFile;
        end

        % Value changed function: MotionCapture_X_EditField
        function MotionCapture_X_EditFieldValueChanged(app, event)
            value = app.MotionCapture_X_EditField.Value;
            app.markerLocation.X = value;
            app.saveCord();
        end

        % Value changed function: MotionCapture_Y_EditField
        function MotionCapture_Y_EditFieldValueChanged(app, event)
            value = app.MotionCapture_Y_EditField.Value;
            app.markerLocation.Y = value;
            app.saveCord();
        end

        % Value changed function: MotionCapture_Z_EditField
        function MotionCapture_Z_EditFieldValueChanged(app, event)
            value = app.MotionCapture_Z_EditField.Value;
            app.markerLocation.Z = value;
            app.saveCord();
        end

        % Value changed function: MotionCaptureTol_X_EditField
        function MotionCaptureTol_X_EditFieldValueChanged(app, event)
            value = app.MotionCaptureTol_X_EditField.Value;
            app.markerTolerance.X = value;
            app.saveTolerance();
        end

        % Value changed function: MotionCaptureTol_Y_EditField
        function MotionCaptureTol_Y_EditFieldValueChanged(app, event)
            value = app.MotionCaptureTol_Y_EditField.Value;
            app.markerTolerance.Y = value;
            app.saveTolerance();
        end

        % Value changed function: MotionCaptureTol_Z_EditField
        function MotionCaptureTol_Z_EditFieldValueChanged(app, event)
            value = app.MotionCaptureTol_Z_EditField.Value;
            app.markerTolerance.Z = value;
            app.saveTolerance();
        end

        % Selection changed function: SETTINGSButtonGroup
        function SETTINGSButtonGroupSelectionChanged(app, event)

        end

        % Button down function: SETTINGSButtonGroup
        function SETTINGSButtonGroupButtonDown(app, event)
            
        end

        % Button down function: graph
        function graphButtonDown(app, event)
            disp(app.debugData1)
            plot(app.graph, app.debugData1)
        end

        % Value changed function: plot_options_beforeSync_BloodOxygention
        function plot_options_beforeSync_BloodOxygentionValueChanged(app, event)
            app.nonSyncPlots(app.plot_options_beforeSync_BloodOxygention.Text) = ~(app.nonSyncPlots(app.plot_options_beforeSync_BloodOxygention.Text));
            app.reFreshplot();
            
        end

        % Button down function: renderTab
        function renderTabButtonDown(app, event)
                setOfNonSyncName = {app.plot_options_beforeSync_BloodOxygention.Text, app.plot_options_beforeSync_MIDI.Text, app.plot_options_beforeSync_sEMG.Text, app.plot_options_beforeSync_MotionCapture.Text, app.plot_options_beforeSync_sEMG.Text, app.plot_options_beforeSync_MotionCapture.Text};
                setOfSyncName = {app.plot_options_afterSync_BloodOxygention.Text, app.plot_options_afterSync_MIDI.Text, app.plot_options_afterSync_sEMG.Text, app.plot_options_afterSync_MotionCapture.Text, app.plot_options_afterSync_sEMG.Text, app.plot_options_afterSync_MotionCapture.Text};
                values = [false, false, false, false, false, false]

                app.nonSyncPlots = configureDictionary("string", "logical");
                app.syncPlots = configureDictionary("string", "logical");
                
                for i = 1:length(setOfNonSyncName)
                    key = setOfNonSyncName{i};
                    if ~(app.nonSyncPlots.isKey(key))
                        app.nonSyncPlots = insert(app.nonSyncPlots, key, false);
                    end
                end

                for i = setOfSyncName
                    if ~(app.syncPlots.isKey(i))
                        app.syncPlots = insert(app.syncPlots, i, false);
                    end
                end

                disp(app.nonSyncPlots)

        end

        % Value changed function: plot_options_beforeSync_MIDI
        function plot_options_beforeSync_MIDIValueChanged(app, event)
            app.nonSyncPlots(app.plot_options_beforeSync_MIDI.Text) = ~(app.nonSyncPlots(app.plot_options_beforeSync_MIDI.Text));
            app.reFreshplot();
            
        end

        % Value changed function: plot_options_beforeSync_sEMG
        function plot_options_beforeSync_sEMGValueChanged2(app, event)
            app.nonSyncPlots(app.plot_options_beforeSync_sEMG.Text) = ~(app.nonSyncPlots(app.plot_options_beforeSync_sEMG.Text));
            app.reFreshplot();
            
        end

        % Value changed function: plot_options_beforeSync_MotionCapture
        function plot_options_beforeSync_MotionCaptureValueChanged2(app, event)
            app.nonSyncPlots(app.plot_options_beforeSync_MotionCapture.Text) = ~(app.nonSyncPlots(app.plot_options_beforeSync_MotionCapture.Text));
            app.reFreshplot();
            
        end

        % Callback function
        function plot_options_beforeSync_sEMGValueChanged(app, event)
            app.nonSyncPlots(app.plot_options_beforeSync_sEMG.Text) = ~(app.nonSyncPlots(app.plot_options_beforeSync_sEMG.Text));
            app.reFreshplot();
        end

        % Callback function
        function plot_options_beforeSync_MotionCaptureValueChanged(app, event)
            app.nonSyncPlots(app.plot_options_afterSync_MotionCapture.Text) = ~(app.nonSyncPlots(app.plot_options_afterSync_MotionCapture.Text));
            app.reFreshplot();
            
        end

        % Value changed function: plot_options_afterSync_BloodOxygention
        function plot_options_afterSync_BloodOxygentionValueChanged(app, event)
            app.syncPlots(app.plot_options_afterSync_BloodOxygention.Text) = ~(app.syncPlots(app.plot_options_afterSync_BloodOxygention.Text));
            app.reFreshplot();
        end

        % Callback function
        function plot_options_afterSync_sEMGValueChanged(app, event)
app.syncPlots(app.plot_options_afterSync_sEMG.Text) = ~(app.syncPlots(app.plot_options_afterSync_sEMG.Text));
            app.reFreshplot();            
        end

        % Value changed function: plot_options_afterSync_MotionCapture
        function plot_options_afterSync_MotionCaptureValueChanged2(app, event)
app.syncPlots(app.plot_options_afterSync_MotionCapture.Text) = ~(app.syncPlots(app.plot_options_afterSync_MotionCapture.Text));
            app.reFreshplot();            
        end

        % Value changed function: plot_options_afterSync_sEMG
        function plot_options_afterSync_sEMGValueChanged2(app, event)
app.syncPlots(app.plot_options_afterSync_sEMG.Text) = ~(app.syncPlots(app.plot_options_afterSync_sEMG.Text));
            app.reFreshplot();            
        end

        % Value changed function: plot_options_afterSync_MIDI
        function plot_options_afterSync_MIDIValueChanged(app, event)
app.syncPlots(app.plot_options_afterSync_MIDI.Text) = ~(app.syncPlots(app.plot_options_afterSync_MIDI.Text));
            app.reFreshplot();            
        end

        % Callback function
        function plot_options_afterSync_MotionCaptureValueChanged(app, event)
app.syncPlots(app.plot_options_afterSync_MotionCapture.Text) = ~(app.syncPlots(app.plot_options_afterSync_MotionCapture.Text));
            app.reFreshplot();            
        end

        % Button pushed function: 
        % plot_options_beforeSync_BloodOxygention_Color
        function plot_options_beforeSync_BloodOxygention_ColorPushed(app, event)
            c = uisetcolor;
            app.plot_options_beforeSync_BloodOxygention_Color.BackgroundColor = c;
            app.beforeSync_BloodOxygentionColor = c;
            app.reFreshplot();

        end

        % Button pushed function: plot_options_beforeSync_MIDI_color
        function plot_options_beforeSync_MIDI_colorButtonPushed(app, event)
            c = uisetcolor;
            app.plot_options_beforeSync_MIDI_color.BackgroundColor = c;
            app.beforeSync_MIDIColor = c;
            app.reFreshplot();

        end

        % Button pushed function: plot_options_beforeSync_sEMG_color
        function plot_options_beforeSync_sEMG_colorButtonPushed2(app, event)
            c = uisetcolor;
            app.plot_options_beforeSync_sEMG_color.BackgroundColor = c;
            app.beforeSync_sMEGColor = c;
            app.reFreshplot();

        end

        % Button pushed function: 
        % plot_options_beforeSync_MotionCapture_color
        function plot_options_beforeSync_MotionCapture_colorButtonPushed2(app, event)
            c = uisetcolor;
            app.plot_options_beforeSync_MotionCapture_color.BackgroundColor = c;
            app.beforeSync_MotionCaptureColor = c;
            app.reFreshplot();

        end

        % Callback function
        function plot_options_beforeSync_sEMG_colorButtonPushed(app, event)
            c = uisetcolor;
            app.plot_options_beforeSync_sEMG_color.BackgroundColor = c;
            app.beforeSync_sMEGColor = c;
                        app.reFreshplot();

        end

        % Callback function
        function plot_options_beforeSync_MotionCapture_colorButtonPushed(app, event)
            c = uisetcolor;
            app.plot_options_beforeSync_MotionCapture_color.BackgroundColor = c;
            app.beforeSync_MotionCaptureColor = c;
                        app.reFreshplot();

        end

        % Button pushed function: 
        % plot_options_afterSync_BloodOxygention_color
        function plot_options_afterSync_BloodOxygention_colorButtonPushed(app, event)
            c = uisetcolor;
            app.plot_options_afterSync_BloodOxygention_color.BackgroundColor = c;
            app.afterSync_BloodOxygentionColor = c;
            app.reFreshplot(); 
        end

        % Button pushed function: SynchronizeButton
        function SynchronizeButtonPushed(app, event)
            [midi, sEMG, blood, motion] = extractData( ...
    app.SynchronisationSignalFile, ...
    app.sEMGsensorFile, app.sEMG_sample_rate, ...
    app.BloodOxygentionSensorFile, app.BloodOxygention_sample_rate, ...
    app.MotionCaptureFile, app.MotionCapture_sample_rate, [app.markerLocation.X app.markerLocation.Y app.markerLocation.Z], [app.markerTolerance.X app.markerTolerance.Y app.markerTolerance.Z], ...
    app.base_sample_rate, app.frame_size, 0);

            %app.Data_AfterSync_MIDI = midi.data;
            app.Data_AfterSync_Blood_L = blood.data(1, :);
            app.Data_AfterSync_Blood_R = blood.data(2, :);
            app.Data_AfterSync_Motion = motion.data;
            app.Data_AfterSync_sEMG_L = sEMG.data(1, :);
            app.Data_AfterSync_sEMG_R = sEMG.data(2, :);
        end

        % Value changed function: sEMG_EditField
        function sEMG_EditFieldValueChanged(app, event)
 
            app.MotionCapture_sample_rate = app.sEMG_EditField.Value;
        end

        % Value changed function: BloodOxygention_EditField
        function BloodOxygention_EditFieldValueChanged(app, event)
            app.BloodOxygention_sample_rate = app.BloodOxygention_EditField.Value;
           
        end

        % Value changed function: MotionCapture_EditField
        function MotionCapture_EditFieldValueChanged(app, event)
            value = app.MotionCapture_EditField.Value;
            app.MotionCapture_sample_rate = value;
        end

        % Value changed function: Base_EditField
        function Base_EditFieldValueChanged(app, event)
            value = app.Base_EditField.Value;
            app.base_sample_rate = value;
        end

        % Value changed function: FrameSIze_EditField
        function FrameSIze_EditFieldValueChanged(app, event)
            value = app.FrameSIze_EditField.Value;
            app.frame_size = value;
        end

        % Button pushed function: plot_options_afterSync_MIDI_color
        function plot_options_afterSync_MIDI_colorButtonPushed(app, event)
            c = uisetcolor;
            app.plot_options_afterSync_MIDI_color.BackgroundColor = c;
            app.afterSync_MIDIColor = c;
            app.reFreshplot();
        end

        % Button pushed function: plot_options_afterSync_sEMG_color
        function plot_options_afterSync_sEMG_colorButtonPushed(app, event)
            c = uisetcolor;
            app.plot_options_afterSync_sEMG_color.BackgroundColor = c;
            app.afterSync_sMEGColor = c;
            app.reFreshplot();
        end

        % Button pushed function: 
        % plot_options_afterSync_MotionCapture_color
        function plot_options_afterSync_MotionCapture_colorButtonPushed(app, event)
            c = uisetcolor;
            app.plot_options_afterSync_MotionCapture_color.BackgroundColor = c;
            app.afterSync_MotionCaptureColor = c;
            app.reFreshplot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout2 = uigridlayout(app.Tab);
            app.BloodOxygentionSensorButton = uibutton(app.GridLayout2, 'push');
            app.BloodOxygentionSensorButton.ButtonPushedFcn = createCallbackFcn(app, @BloodOxygentionSensorButtonPushed, true);
            app.BloodOxygentionSensorButton.Text = {'Blood Oxygention'; 'Sensor'};
            app.MIDIcaptureButton = uibutton(app.GridLayout2, 'push');
            app.MIDIcaptureButton.ButtonPushedFcn = createCallbackFcn(app, @MIDIcaptureButtonPushed, true);
            app.MIDIcaptureButton.Text = 'MIDI capture';
            app.sEMGsensorButton = uibutton(app.GridLayout2, 'push');
            app.sEMGsensorButton.ButtonPushedFcn = createCallbackFcn(app, @sEMGsensorButtonPushed, true);
            app.sEMGsensorButton.Text = 'sEMG sensor';
            app.MotionCaptureButton = uibutton(app.GridLayout2, 'push');
            app.MotionCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @MotionCaptureButtonPushed, true);
            app.MotionCaptureButton.Text = 'Motion Capture';
            app.Label_BloodOxygention = uilabel(app.GridLayout2);
            app.Label_BloodOxygention.Text = 'Label2';
            app.Label_MIDI = uilabel(app.GridLayout2);
            app.Label_MIDI.Text = 'Label4';
            app.Label_sEMG = uilabel(app.GridLayout2);
            app.Label_sEMG.Text = 'Label5';
            app.Label_MotionCapture = uilabel(app.GridLayout2);
            app.Label_MotionCapture.Text = 'Label6';
            app.SynchronizeButton = uibutton(app.GridLayout2, 'push');
            app.SynchronizeButton.ButtonPushedFcn = createCallbackFcn(app, @SynchronizeButtonPushed, true);
            app.SynchronizeButton.Text = 'Synchronize';
            app.MotionCaptureMarkerCoordinatesPanel = uipanel(app.GridLayout2);
            app.MotionCaptureMarkerCoordinatesPanel.Title = 'Motion Capture Marker Coordinates';
            app.GridLayout = uigridlayout(app.MotionCaptureMarkerCoordinatesPanel);
            app.MotionCapture_X_EditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapture_X_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_X_EditFieldValueChanged, true);
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout);
            app.ZLabel.Text = 'Z';
            app.MotionCapture_Y_EditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapture_Y_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_Y_EditFieldValueChanged, true);
            app.MotionCapture_Z_EditField = uieditfield(app.GridLayout, 'numeric');
            app.MotionCapture_Z_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_Z_EditFieldValueChanged, true);
            app.MotionCaptureMarkerTolerancePanel = uipanel(app.GridLayout2);
            app.MotionCaptureMarkerTolerancePanel.Title = 'Motion Capture Marker Tolerance';
            app.GridLayout_2 = uigridlayout(app.MotionCaptureMarkerTolerancePanel);
            app.XLabel_2 = uilabel(app.GridLayout_2);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.GridLayout_2);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.GridLayout_2);
            app.ZLabel_2.Text = 'Z';
            app.MotionCaptureTol_X_EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.MotionCaptureTol_X_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureTol_X_EditFieldValueChanged, true);
            app.MotionCaptureTol_Y_EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.MotionCaptureTol_Y_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureTol_Y_EditFieldValueChanged, true);
            app.MotionCaptureTol_Z_EditField = uieditfield(app.GridLayout_2, 'numeric');
            app.MotionCaptureTol_Z_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCaptureTol_Z_EditFieldValueChanged, true);
            app.SampleRatesPanel = uipanel(app.GridLayout2);
            app.SampleRatesPanel.Title = 'Sample Rates';
            app.GridLayout_3 = uigridlayout(app.SampleRatesPanel);
            app.sEMG_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.sEMG_EditField.ValueChangedFcn = createCallbackFcn(app, @sEMG_EditFieldValueChanged, true);
            app.sEMG_EditField.Value = 1000;
            app.sEMG_Label = uilabel(app.GridLayout_3);
            app.sEMG_Label.Text = 'sEMG';
            app.BloodOxygention_Label = uilabel(app.GridLayout_3);
            app.BloodOxygention_Label.Text = 'Blood Oxygention';
            app.MotionCaptureLabel = uilabel(app.GridLayout_3);
            app.MotionCaptureLabel.Text = 'Motion Capture';
            app.BloodOxygention_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.BloodOxygention_EditField.ValueChangedFcn = createCallbackFcn(app, @BloodOxygention_EditFieldValueChanged, true);
            app.BloodOxygention_EditField.Value = 1000;
            app.MotionCapture_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.MotionCapture_EditField.ValueChangedFcn = createCallbackFcn(app, @MotionCapture_EditFieldValueChanged, true);
            app.MotionCapture_EditField.Value = 300;
            app.Base_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.Base_EditField.ValueChangedFcn = createCallbackFcn(app, @Base_EditFieldValueChanged, true);
            app.Base_EditField.Value = 1000;
            app.FrameSIzeLabel = uilabel(app.GridLayout_3);
            app.FrameSIzeLabel.Text = 'Frame SIze';
            app.FrameSIze_EditField = uieditfield(app.GridLayout_3, 'numeric');
            app.FrameSIze_EditField.ValueChangedFcn = createCallbackFcn(app, @FrameSIze_EditFieldValueChanged, true);
            app.FrameSIze_EditField.Value = 5001;
            app.Base_Label = uilabel(app.GridLayout_3);
            app.Base_Label.Text = 'Base';
            app.renderTab = uitab(app.TabGroup);
            app.renderTab.Title = 'render';
            app.renderTab.ButtonDownFcn = createCallbackFcn(app, @renderTabButtonDown, true);
            app.graph = uiaxes(app.renderTab);
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            zlabel(app.graph, 'Z')
            app.graph.ButtonDownFcn = createCallbackFcn(app, @graphButtonDown, true);
            app.SETTINGSButtonGroup = uibuttongroup(app.renderTab);
            app.SETTINGSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SETTINGSButtonGroupSelectionChanged, true);
            app.SETTINGSButtonGroup.Title = 'SETTINGS';
            app.SETTINGSButtonGroup.ButtonDownFcn = createCallbackFcn(app, @SETTINGSButtonGroupButtonDown, true);
            app.plot_options_beforeSync_MIDI = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_beforeSync_MIDI.ValueChangedFcn = createCallbackFcn(app, @plot_options_beforeSync_MIDIValueChanged, true);
            app.plot_options_beforeSync_MIDI.Text = 'MIDI';
            app.plot_options_beforeSync_sEMG = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_beforeSync_sEMG.ValueChangedFcn = createCallbackFcn(app, @plot_options_beforeSync_sEMGValueChanged2, true);
            app.plot_options_beforeSync_sEMG.Text = 'sEMG ';
            app.plot_options_beforeSync_MotionCapture = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_beforeSync_MotionCapture.ValueChangedFcn = createCallbackFcn(app, @plot_options_beforeSync_MotionCaptureValueChanged2, true);
            app.plot_options_beforeSync_MotionCapture.Text = 'Motion Capture';
            app.plot_options_beforeSync_BloodOxygention = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_beforeSync_BloodOxygention.ValueChangedFcn = createCallbackFcn(app, @plot_options_beforeSync_BloodOxygentionValueChanged, true);
            app.plot_options_beforeSync_BloodOxygention.Text = 'Blood Oxygention';
            app.BEFORESYNCLabel = uilabel(app.SETTINGSButtonGroup);
            app.BEFORESYNCLabel.Text = 'BEFORE SYNC';
            app.AFTERSYNCLabel = uilabel(app.SETTINGSButtonGroup);
            app.AFTERSYNCLabel.Text = 'AFTER SYNC';
            app.plot_options_afterSync_MIDI = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_afterSync_MIDI.ValueChangedFcn = createCallbackFcn(app, @plot_options_afterSync_MIDIValueChanged, true);
            app.plot_options_afterSync_MIDI.Text = 'MIDI';
            app.plot_options_afterSync_sEMG = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_afterSync_sEMG.ValueChangedFcn = createCallbackFcn(app, @plot_options_afterSync_sEMGValueChanged2, true);
            app.plot_options_afterSync_sEMG.Text = 'sEMG ';
            app.plot_options_afterSync_MotionCapture = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_afterSync_MotionCapture.ValueChangedFcn = createCallbackFcn(app, @plot_options_afterSync_MotionCaptureValueChanged2, true);
            app.plot_options_afterSync_MotionCapture.Text = 'Motion Capture';
            app.plot_options_afterSync_BloodOxygention = uicheckbox(app.SETTINGSButtonGroup);
            app.plot_options_afterSync_BloodOxygention.ValueChangedFcn = createCallbackFcn(app, @plot_options_afterSync_BloodOxygentionValueChanged, true);
            app.plot_options_afterSync_BloodOxygention.Text = 'Blood Oxygention';
            app.PLOTLabel = uilabel(app.SETTINGSButtonGroup);
            app.PLOTLabel.Text = 'PLOT';
            app.Plot_options_plot_maxX = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.xLabel = uilabel(app.SETTINGSButtonGroup);
            app.xLabel.Text = '≤ x ≤';
            app.Plot_options_plot_minX = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.Plot_options_plot_minY = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.yLabel = uilabel(app.SETTINGSButtonGroup);
            app.yLabel.Text = '≤ y ≤';
            app.Plot_options_plot_maxY = uieditfield(app.SETTINGSButtonGroup, 'numeric');
            app.Plot_options_plot_grid = uicheckbox(app.SETTINGSButtonGroup);
            app.Plot_options_plot_grid.Text = 'Grid';
            app.Plot_options_plot_grid_2 = uicheckbox(app.SETTINGSButtonGroup);
            app.Plot_options_plot_grid_2.Text = 'Legend';
            app.plot_options_beforeSync_BloodOxygention_Color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_beforeSync_BloodOxygention_Color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_beforeSync_BloodOxygention_ColorPushed, true);
            app.plot_options_beforeSync_BloodOxygention_Color.Text = '';
            app.plot_options_beforeSync_MIDI_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_beforeSync_MIDI_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_beforeSync_MIDI_colorButtonPushed, true);
            app.plot_options_beforeSync_MIDI_color.Text = '';
            app.plot_options_beforeSync_sEMG_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_beforeSync_sEMG_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_beforeSync_sEMG_colorButtonPushed2, true);
            app.plot_options_beforeSync_sEMG_color.Text = '';
            app.plot_options_beforeSync_MotionCapture_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_beforeSync_MotionCapture_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_beforeSync_MotionCapture_colorButtonPushed2, true);
            app.plot_options_beforeSync_MotionCapture_color.Text = '';
            app.plot_options_afterSync_BloodOxygention_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_afterSync_BloodOxygention_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_afterSync_BloodOxygention_colorButtonPushed, true);
            app.plot_options_afterSync_BloodOxygention_color.Text = '';
            app.plot_options_afterSync_MIDI_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_afterSync_MIDI_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_afterSync_MIDI_colorButtonPushed, true);
            app.plot_options_afterSync_MIDI_color.Text = '';
            app.plot_options_afterSync_sEMG_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_afterSync_sEMG_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_afterSync_sEMG_colorButtonPushed, true);
            app.plot_options_afterSync_sEMG_color.Text = '';
            app.plot_options_afterSync_MotionCapture_color = uibutton(app.SETTINGSButtonGroup, 'push');
            app.plot_options_afterSync_MotionCapture_color.ButtonPushedFcn = createCallbackFcn(app, @plot_options_afterSync_MotionCapture_colorButtonPushed, true);
            app.plot_options_afterSync_MotionCapture_color.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = piano

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end